classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes_3             matlab.ui.control.UIAxes
        LoadImageButton      matlab.ui.control.Button
        FiltersButtonGroup   matlab.ui.container.ButtonGroup
        IdealButton          matlab.ui.control.RadioButton
        BTWButton            matlab.ui.control.RadioButton
        GaussianButton       matlab.ui.control.RadioButton
        LevelEditFieldLabel  matlab.ui.control.Label
        LevelEditField       matlab.ui.control.NumericEditField
        UIAxes_4             matlab.ui.control.UIAxes
        D0SliderLabel        matlab.ui.control.Label
        D0Slider             matlab.ui.control.Slider
        PassButtonGroup      matlab.ui.container.ButtonGroup
        LowButton_2          matlab.ui.control.RadioButton
        HighButton           matlab.ui.control.RadioButton
    end

    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [fileName, pathName] = uigetfile({'*.jpeg';'*.jpg'}, 'Select Image');
            file = [pathName, fileName];
            global I;
            global G;
            global m;
            global n;
            global D0;
            global level;
            I = im2double(rgb2gray(imread(file)));
            D0 = 10;
            level = 1;
            [m, n ] = size(I);
            imshow(I,'Parent',app.UIAxes);
            FiltersButtonGroupSelectionChanged(app, event);
        end

        % Selection changed function: FiltersButtonGroup
        function FiltersButtonGroupSelectionChanged(app, event)
            selectedButton = app.FiltersButtonGroup.SelectedObject;
            global filter;
            global D0;
            global m;
            global n;
            global I;
            global G;
            global level;
            if selectedButton.Text == "Ideal"
                filter = ideal_filter(m, n, D0);
            elseif selectedButton.Text == "BTW"
                filter = btw_filter(m, n, D0, level);
            else
                filter = gauss_filter(m, n, D0);
            end
            if app.PassButtonGroup.SelectedObject.Text == "High"
                I_fft = fftshift(fft2(I));
                G = ifft2(ifftshift(I_fft.*(1 - filter)));
                mesh(app.UIAxes_3, 1- filter);
                histogram(app.UIAxes_4, 1 - filter,'DisplayStyle','stairs', 'Normalization','probability');
            else
                I_fft = fftshift(fft2(I));
                G = ifft2(ifftshift(I_fft.*(filter)));
                mesh(app.UIAxes_3, filter);
                histogram(app.UIAxes_4, filter,'DisplayStyle','stairs', 'Normalization','probability');
            end
            imshow(G,'Parent',app.UIAxes_2);
        end

        % Value changed function: D0Slider
        function D0SliderValueChanged(app, event)
            value = app.D0Slider.Value;
            global D0;
            D0 = value;
            FiltersButtonGroupSelectionChanged(app, event);
        end

        % Selection changed function: PassButtonGroup
        function PassButtonGroupSelectionChanged(app, event)
            selectedButton = app.PassButtonGroup.SelectedObject;
            FiltersButtonGroupSelectionChanged(app, event);
        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)
            value = app.LevelEditField.Value;
            global level;
            level = value;
            FiltersButtonGroupSelectionChanged(app, event);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.FiltersButtonGroup = uibuttongroup(app.UIFigure);
            app.FiltersButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FiltersButtonGroupSelectionChanged, true);
            app.FiltersButtonGroup.Title = 'Filters';
            app.IdealButton = uiradiobutton(app.FiltersButtonGroup);
            app.IdealButton.Text = 'Ideal';
            app.IdealButton.Value = true;
            app.BTWButton = uiradiobutton(app.FiltersButtonGroup);
            app.BTWButton.Text = 'BTW';
            app.GaussianButton = uiradiobutton(app.FiltersButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.LevelEditFieldLabel = uilabel(app.FiltersButtonGroup);
            app.LevelEditFieldLabel.Text = 'Level';
            app.LevelEditField = uieditfield(app.FiltersButtonGroup, 'numeric');
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.LevelEditField.Value = 1;
            app.UIAxes_4 = uiaxes(app.UIFigure);
            app.D0SliderLabel = uilabel(app.UIFigure);
            app.D0SliderLabel.Text = 'D0';
            app.D0Slider = uislider(app.UIFigure);
            app.D0Slider.Limits = [0 40];
            app.D0Slider.ValueChangedFcn = createCallbackFcn(app, @D0SliderValueChanged, true);
            app.D0Slider.Value = 10;
            app.PassButtonGroup = uibuttongroup(app.UIFigure);
            app.PassButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PassButtonGroupSelectionChanged, true);
            app.PassButtonGroup.Title = 'Pass';
            app.LowButton_2 = uiradiobutton(app.PassButtonGroup);
            app.LowButton_2.Text = 'Low';
            app.LowButton_2.Value = true;
            app.HighButton = uiradiobutton(app.PassButtonGroup);
            app.HighButton.Text = 'High';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end